classdef GEWinspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        DropDownPlotType        matlab.ui.control.DropDown
        animateSwitch           matlab.ui.control.Switch
        animateSwitchLabel      matlab.ui.control.Label
        DropDownModeField       matlab.ui.control.DropDown
        plotDropDown_2Label     matlab.ui.control.Label
        DropDownDispersionPlot  matlab.ui.control.DropDown
        plotDropDownLabel       matlab.ui.control.Label
        DispersionUIAxes        matlab.ui.control.UIAxes
        ModeUIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dat  GEWdat   % Description
        modeIdx  % mode index
        mode GEWdat  % selected mode's data
        fieldVariablenameList string 
        fieldFunctionList cell
        hDisp   % graphics handles of the dispersion plot
        hMode   % graphics handles of the plotted mode's field
        dcm
        plotDelayTimer timer % only plot after the data tip dragging has finished
        AnimationTimer timer % to animate the mode plot at a fixed rate
        modeField % field of the selected mode interpolated to plotting grid
        componentNames % names of the field's components, i.e., x, y, z, xx, xy, ...
        time = 0  % time at which to display the field: stepped during animation
    end
    
    methods (Access = private)

        function selectMode(app, sel)
            [i,j] = ind2sub(size(app.dat.k), sel);
            app.modeIdx.i = i; app.modeIdx.j = j; app.modeIdx.sel = sel;
            app.mode = app.dat.extractModes(i,j);
            app.time = 0; % reset time
        end

        function markMode(app)
            % Mark data tip in dispersion graph. Only needed when re-plotting
            % dispersion with an existing data tip. 
            sel = app.modeIdx.sel;
            datatip(app.hDisp,'DataIndex',sel);
        end

        function plotDispersionWavenumbersComplex(app)
            ax = app.DispersionUIAxes; 

            scaleKr = app.dat.gew.h; 
            stringKr = '$\Re k h$';
            scaleKi = app.dat.gew.h; 
            stringKi = '$\Im k h$';
            scaleW  = app.dat.gew.h/app.dat.gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';

            W  = real(app.dat.w)*scaleW; 
            Kr = real(app.dat.k)*scaleKr;
            Ki = imag(app.dat.k)*scaleKi;
            app.hDisp = plot3(ax, Kr(:), Ki(:), W(:), '.', 'Color','#3B518B', 'MarkerSize', 4, 'DisplayName','all');
            ax.XLim = [min(Kr(:)), max(Kr(:))+eps];
            ax.YLim = [min(Ki(:)), max(Ki(:))+eps]/20;
            ax.ZLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringKr; 
            ax.YLabel.String = stringKi; 
            ax.ZLabel.String = stringW;

            markMode(app); % mark mode
        end
        
        function plotDispersionWavenumbers(app)
            ax = app.DispersionUIAxes; 

            scaleKr = app.dat.gew.h; 
            stringKr = '$\Re k h$';
            scaleW  = app.dat.gew.h/app.dat.gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';

            W  = real(app.dat.w)*scaleW; 
            Kr = real(app.dat.k)*scaleKr;
            app.hDisp = plot(ax, Kr(:), W(:), '.', 'Color','#3B518B', 'MarkerSize', 4, 'DisplayName','all');
            ax.XLim = [min(Kr(:)), max(Kr(:))+eps];
            ax.YLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringKr; 
            ax.YLabel.String = stringW;
            
            markMode(app); % mark mode
        end

        function plotDispersionPhasevel(app)
            ax = app.DispersionUIAxes; 

            scaleW = app.dat.gew.h/app.dat.gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';
            scaleCp = 1/app.dat.gew.lay{1}.mat.ct;
            stringCp = '$c_\mathrm{p}/c_\mathrm{t} = (\omega / \Re k)/c_\mathrm{t}$';

            W = real(app.dat.w)*scaleW; 
            Cp = real(app.dat.w)./real(app.dat.k)*scaleCp;
            app.hDisp = plot(ax, W(:), Cp(:), '.', 'Color','#3B518B', 'MarkerSize', 4, 'DisplayName','all');
            ax.YLim = [0, app.dat.gew.lay{1}.mat.cl*2]*scaleCp;
            ax.XLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringW; 
            ax.YLabel.String = stringCp;
            
            markMode(app); % mark mode
        end

        function plotDispersionEnergyvelAxial(app)
            ax = app.DispersionUIAxes; 

            scaleW = app.dat.gew.h/app.dat.gew.lay{1}.mat.ct; 
            stringW = '$\omega h/c_\mathrm{t}$';
            scaleCe = 1/app.dat.gew.lay{1}.mat.ct;
            stringCe = '$c_\mathrm{e}/c_\mathrm{t}$';

            W = real(app.dat.w)*scaleW; 
            Ce = energyVelAxial(app.dat)*scaleCe;
            app.hDisp = plot(ax, W(:), Ce(:), '.', 'Color','#3B518B', 'MarkerSize', 4, 'DisplayName','all');
            ax.YLim = [min(Ce(:)), max(Ce(:))+eps]*1.05;
            ax.XLim = [min(W(:)),  max(W(:))+eps];
            ax.XLabel.String = stringW; 
            ax.YLabel.String = stringCe;
            
            markMode(app); % mark mode
        end
        
        function updateDispersionWavenumbers(app)
            W = real(app.dat.w); 
            Kr = real(app.dat.k);
            set(app.hDisp, 'XData', Kr, 'YData', W);
        end

        function updateDispersionPhasevel(app)
            W = real(app.dat.w); 
            Cp = real(app.dat.w)./real(app.dat.k);
            set(app.hDisp, 'XData', W, 'YData', Cp);
        end

        function dataTipString = onPointSelected(app, event)
            % define datatip string to display in dispersion curves:
            dataTipString = "";
            if event.Target.Parent == app.DispersionUIAxes
                % % Restart debounce timer
                % if strcmp(app.plotDelayTimer.Running,'on')
                %     stop(app.plotDelayTimer)
                % end
                % start(app.plotDelayTimer)

                selectMode(app,event.DataIndex);
                fieldTypeIdx = app.DropDownModeField.Value; 
                fieldGetterFunction = app.fieldFunctionList{fieldTypeIdx}; % function to retrive the desired field (displacement, stress, etc)
                app.interpolateField(fieldGetterFunction);
                plotMode(app); % NOTE: plotMode() should only plot, not interpolate!
            elseif event.Target.Parent == app.ModeUIAxes
                val = event.Position(1); % x-coordinate on plot
                dataTipString = num2str(round(val,3));
            else
            end
        end

        function componentNames = interpolateField(app, getField)
            fieldData = getField(app.mode); % getField() is a function handle that retrieves the desired field (displacement, stress,...)
            kz = real( sqrt((app.mode.w/app.dat.gew.lay{1}.mat.ct2)^2 - app.mode.k^2) ); % vertical wavenumber
            Nlambda = ceil( app.dat.gew.lay{1}.h/(2*pi/kz) ); % number of wavelengths through the thickness of the layer
            Ninterp = 30*Nlambda+1; if Ninterp < 101, Ninterp = 101; end
            [ai, zi] = GEWinterpolate(app.dat.gew, fieldData, Ninterp);
            maxA = max(abs(ai), [], 'all');
            ai = ai/maxA;
            s = size(ai); 
            app.modeField.value = reshape(ai, [s(1), prod(s(2:end))]); % size: length(zi) x number of components
            app.modeField.coord = zi;
            if nargout >= 1
                componentNames = app.getComponentNames(size(fieldData{1})); % return value
            end
        end

        function comps = getComponentNames(app, s)
            s = s(4:end); % tensor components start at fourth dimension
            coord = app.dat.gew.coordNames; 
            comps = coord; % initialize with one component for each coordinate 
            isReduced = any(s < length(coord)); 
            if isReduced % field has only the polarization components "udof"
                comps = coord(app.dat.gew.udof); % reduce also the component names
            end
            ndim = length(s); % rank of the tensor 
            if ndim == 0     % 0th rank tensor = scalar 
                comps = []; 
            elseif ndim == 2 % 2nd rank tensor
                comps = comps + comps.'; 
                comps = comps(:).'; 
            end

            app.fieldVariablenameList; 
        end
               
        function plotModeComplex(app)
            % retrieve field and additional data
            gew = app.dat.gew;
            ui = app.modeField.value; 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hr = plot(app.ModeUIAxes, real(ui), zi/gew.h, '-');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hi = plot(app.ModeUIAxes, imag(ui), zi/gew.h, '--');
            app.hMode = [hr; hi];
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value))
            ylabel(app.ModeUIAxes, 'coordinate $z/h$')
            % legend(app.ModeUIAxes, ["$\Re " + app.dat.gew.displComp + "$", "$\Im " + app.dat.gew.displComp + "$"], 'Location','northeastoutside')
            legend(app.ModeUIAxes, ["$\Re$ "+app.modeField.componentNames, "$\Im$ "+app.modeField.componentNames], 'Location','northeastoutside');
            app.ModeUIAxes.XLim = [-1.01, 1.01];
        end

        function plotModeMagnitude(app)
            % retrieve field and additional data
            gew = app.dat.gew;
            ui = app.modeField.value; 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            h = plot(app.ModeUIAxes, abs(ui), zi/gew.h, '-');
            app.hMode = h; 
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value))
            ylabel(app.ModeUIAxes, 'coordinate $z/h$')
            % legend(app.ModeUIAxes, "$|"+app.dat.gew.displComp+"|$", 'Location','northeastoutside')
            legend(app.ModeUIAxes, "|"+app.modeField.componentNames+"|", 'Location','northeastoutside');
            app.ModeUIAxes.XLim = [0, 1.01];
        end

        function plotModeInstantaneous(app)
            % retrieve field and additional data
            gew = app.dat.gew;
            w = app.mode.w; 
            t = app.time;
            ui = app.modeField.value.*exp(1i*w*t); 
            zi = app.modeField.coord;

            % plot
            hold(app.ModeUIAxes, 'on');
            app.ModeUIAxes.ColorOrderIndex = 1; % reset color order index
            hr = plot(app.ModeUIAxes, real(ui), zi/gew.h, '-');
            app.hMode = hr;
            yline(app.ModeUIAxes, gew.geom.zItf(:)/gew.h, '-', 'HandleVisibility','off')
            xline(app.ModeUIAxes, 0, '-', 'HandleVisibility','off')
            xlabel(app.ModeUIAxes, "norm. "+app.DropDownModeField.Items(app.DropDownModeField.Value))
            ylabel(app.ModeUIAxes, 'coordinate $z/h$')
            % legend(app.ModeUIAxes, "$\Re "+app.dat.gew.displComp+"$", 'Location','northeastoutside')
            legend(app.ModeUIAxes, app.modeField.componentNames, 'Location','northeastoutside');
            app.ModeUIAxes.XLim = [-1.01, 1.01];
        end

        function plotModeWaveVecs(app)
            % extract mode: 
            mode = app.mode;
            ky = squeeze(mode.beta);
            kx = mode.k*ones(size(ky)); 

            % normalize:
            krLengths = sqrt(real(kx).^2 + real(ky).^2);
            kiLengths = sqrt(imag(kx).^2 + imag(ky).^2);
            k0 = max([krLengths; kiLengths]);
            kx = kx/k0; ky = ky/k0; % normalize

            at = []; 
            for loading = mode.gew.halfSpaces
                if loading.at == "bottom"
                    z = -0.55;
                else
                    z = 0.55; 
                end
                at = [at; ones(length(loading.dofA),1)*z];
            end 

            % move origin of wave vectors such that they do not overlap the plate:
            below =  at == -0.55; 
            above =  at ==  0.55; 
            upr    =  real(ky) >  1e-3*abs(real(kx));
            downr  =  real(ky) < -1e-3*abs(real(kx));
            upi    =  imag(ky) >  1e-3*abs(imag(kx));
            downi  =  imag(ky) < -1e-3*abs(imag(kx));
            if any(below & upr | below & upi) % plot all lower bulk wave vectors at same position
                upComps = [real(ky(below & upr)); imag(ky(below & upi))]; % all upwards-going real and imaginary ky
                at(below) = -0.55 - max(abs(upComps));
            end
            if any(above & downr | above & downi) % plot all upper bulk wave vectors at same position
                upComps = [real(ky(above & downr)); imag(ky(above & downi))]; % all downwards-going real and imaginary ky
                at(above) = 0.55 + max(abs(upComps)); 
            end
            
            % prepare quiver plot
            xx = 0;  zz = at;
            [X, Z] = meshgrid(xx, zz); 
            
            % plot
            plotModeComplex(app)
            hold(app.ModeUIAxes, 'on');
            quiver(app.ModeUIAxes, X,Z,real(kx),real(ky),'Color','#3B518B','LineWidth',1.5, 'DisplayName','$\Re \mathbf{k}$');
            quiver(app.ModeUIAxes, X,Z,imag(kx),imag(ky),'Color','#C75E78','LineWidth',1.5, 'DisplayName','$\Im \mathbf{k}$');
            axis(app.ModeUIAxes, 'equal')
            drawnow
            % 
            % axis(app.ModeUIAxes, 'off')
        end

        function plotDispersion(app)
            cla(app.DispersionUIAxes,'reset');
            app.DropDownDispersionPlot.Value(app);  % Value is a function handle to the selected plot type (defined in startupFcn())
        end

        function plotMode(app)
            cla(app.ModeUIAxes,'reset');
            if isnan(app.mode.k)
                text(app.ModeUIAxes, 0.5, 0.5, "nothing to show", "VerticalAlignment","middle","HorizontalAlignment","center","FontSize",15);
                return;
            end
            app.DropDownPlotType.Value(app);  % Value is a function handle to the selected plot type (defined in startupFcn())
        end

        function updateAnimationFrame(app)
            % extract mode :
            gew = app.dat.gew;
            mode = app.mode;
            w = mode.w; % angular frequency
            dt = 1/70*2*pi/w; % increment in physical time
            t = app.time; 

            nLines = size(app.modeField.value,2);
            if numel(app.hMode) > nLines
                hModeList = app.hMode(1:nLines); 
                hRemove = app.hMode(nLines+1:end); 
                delete(hRemove); 
                app.hMode = hModeList; 
            end

            for l = 1:numel(app.hMode)
                app.hMode(l).XData = real(app.modeField.value(:,l).*exp(1i*w*t));
            end
            app.time = t + dt;
            drawnow limitrate nocallbacks
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datIn)
            app.dat = datIn; 
            sels = find(~isnan(app.dat.k)); indsel = floor(length(sels)*1/5.33); % for selection of a data point
            selectMode(app, sels(indsel));
            % [app.modeIdx.i, app.modeIdx.j] = ind2sub(size(app.dat.k), sels(indsel));  % row and col indices of the selected data point

            % do before calling DropDownDispersionPlotValueChanged():
            app.dcm = datacursormode(app.UIFigure);
            set(app.dcm, "Enable", "on");
            app.dcm.UpdateFcn = @(obj,event) onPointSelected(app,event);
            app.modeField.componentNames = []; % is required before calling DropDownDispersionPlotValueChanged() further down

            app.DropDownDispersionPlot.Items = [...
                "wave numbers (real)",...
                "wave numbers (complex)",...
                "phase velocity",...
                "energy velocity (axial)"];
            app.DropDownDispersionPlot.ItemsData = {...
                @plotDispersionWavenumbers,...
                @plotDispersionWavenumbersComplex,...
                @plotDispersionPhasevel,...
                @plotDispersionEnergyvelAxial};

            app.DropDownModeField.Items = ["displacements", "stress", "power flux density"];
            app.fieldVariablenameList = ["u", "T", "p"]; 
            app.DropDownModeField.ItemsData = 1:length(app.DropDownModeField.Items); % will be used to index the data
            app.fieldFunctionList = {@fieldComponents, @stress, @poyntingVec};
            
            app.DropDownPlotType.Items = ["complex", "magnitude", "instantaneous"];
            app.DropDownPlotType.ItemsData = {@plotModeComplex, @plotModeMagnitude, @plotModeInstantaneous};
            if isa(app.dat, 'GEWdatLeaky')
                app.DropDownPlotType.Items = [app.DropDownPlotType.Items, "wave vectors"];
                app.DropDownPlotType.ItemsData = [app.DropDownPlotType.ItemsData, {@plotModeWaveVecs}];
            end

            app.DropDownDispersionPlotValueChanged();    % initial dispersion plot + select and extract mode data
            app.DropDownModeFieldValueChanged();         % set app.handleModePlot

            % app.plotDelayTimer = timer( ...
            %     'ExecutionMode','singleShot', ...
            %     'StartDelay',0.25, ...   % debounce delay (seconds)
            %     'TimerFcn',@(~,~)plotMode(app));

            app.AnimationTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...   % or 'fixedSpacing'
                    'Period', 0.05, ...                 % seconds between frames
                    'TimerFcn', @(~,~)updateAnimationFrame(app) ...
                );
        end

        % Value changed function: DropDownDispersionPlot
        function DropDownDispersionPlotValueChanged(app, event)
            plotDispersion(app)
        end

        % Value changed function: DropDownModeField
        function DropDownModeFieldValueChanged(app, event)
            if app.animateSwitch.Value % stop animation if it is running
                app.animateSwitch.Value = false; 
                animateSwitchValueChanged(app); % stops the AnimationTimer among other things
            end
            fieldTypeIdx = app.DropDownModeField.Value; 
            fieldGetterFunction = app.fieldFunctionList{fieldTypeIdx}; % function to retrive the desired field (displacement, stress, etc)
            components = app.interpolateField(fieldGetterFunction); % also gets components -> only place where we apply the fieldGetterFunction
            varName = app.fieldVariablenameList(fieldTypeIdx); 
            app.modeField.variableName = varName;
            app.modeField.componentNames = "$"+varName+"_{"+components+"}$"; 
            plotMode(app);
        end

        % Value changed function: animateSwitch
        function animateSwitchValueChanged(app, event)
            isAnimate = app.animateSwitch.Value;
            if isAnimate
                app.DropDownPlotType.Value = @plotModeInstantaneous;
                app.DropDownPlotType.Enable = 'off'; 
                plotMode(app); 
                start(app.AnimationTimer);
                app.ModeUIAxes.XLim = [-1.01, 1.01];
            else
                app.DropDownPlotType.Enable = 'on'; 
                stop(app.AnimationTimer);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.AnimationTimer) && isvalid(app.AnimationTimer) % IMPORTANT: otherwise matlab gets slow!
                stop(app.AnimationTimer)
                delete(app.AnimationTimer)
            end
            if ~isempty(app.plotDelayTimer) && isvalid(app.plotDelayTimer)
                stop(app.plotDelayTimer)
                delete(app.plotDelayTimer)
            end
            delete(app)
        end

        % Value changed function: DropDownPlotType
        function DropDownPlotTypeValueChanged(app, event)
            plotMode(app);
        end

        % Window button motion function: UIFigure
        function mouseMove(app, event)
            % % Only react when mouse is over this axes
            % obj = hittest(app.UIFigure);
            % ax = ancestor(obj,'axes');
            % 
            % if ax ~= app.DispersionUIAxes
            %     app.hCursorHover.XData = NaN; % hide cursor when outside
            %     app.hCursorHover.YData = NaN;
            %     if ~isempty(app.hCursorHover.ZData)
            %         app.hCursorHover.ZData = NaN;
            %     end
            %     return
            % end
            % [hLine, idx, xN, yN, zN] = nearestPoint(app);
            % 
            % % Update cursor
            % app.hCursorHover.XData = xN;
            % app.hCursorHover.YData = yN;
            % if ~isempty(hLine.ZData)
            %     app.hCursorHover.ZData = zN;
            % end
            % 
            % drawnow limitrate nocallbacks
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMove, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModeUIAxes = uiaxes(app.GridLayout);
            title(app.ModeUIAxes, 'Mode', 'Interpreter', 'latex')
            xlabel(app.ModeUIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.ModeUIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.ModeUIAxes, 'Z', 'Interpreter', 'latex')
            colormap(app.ModeUIAxes, 'parula')
            app.DispersionUIAxes = uiaxes(app.GridLayout);
            title(app.DispersionUIAxes, 'Dispersion', 'Interpreter', 'latex')
            xlabel(app.DispersionUIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.DispersionUIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.DispersionUIAxes, 'Z', 'Interpreter', 'latex')
            colormap(app.DispersionUIAxes, 'parula')
            app.plotDropDownLabel = uilabel(app.GridLayout);
            app.plotDropDownLabel.Text = 'plot';
            app.DropDownDispersionPlot = uidropdown(app.GridLayout);
            app.DropDownDispersionPlot.Items = {'wave numbers', 'phase velocity'};
            app.DropDownDispersionPlot.ValueChangedFcn = createCallbackFcn(app, @DropDownDispersionPlotValueChanged, true);
            app.DropDownDispersionPlot.Value = 'wave numbers';
            app.plotDropDown_2Label = uilabel(app.GridLayout);
            app.plotDropDown_2Label.Text = 'plot';
            app.DropDownModeField = uidropdown(app.GridLayout);
            app.DropDownModeField.Items = {'wave vectors'};
            app.DropDownModeField.ItemsData = {'{@plotModeWaveVecs}'};
            app.DropDownModeField.ValueChangedFcn = createCallbackFcn(app, @DropDownModeFieldValueChanged, true);
            app.DropDownModeField.Value = '{@plotModeWaveVecs}';
            app.animateSwitchLabel = uilabel(app.GridLayout);
            app.animateSwitchLabel.Text = 'animate';
            app.animateSwitch = uiswitch(app.GridLayout, 'slider');
            app.animateSwitch.ItemsData = [false true];
            app.animateSwitch.ValueChangedFcn = createCallbackFcn(app, @animateSwitchValueChanged, true);
            app.animateSwitch.Tooltip = {'harmonic animation'};
            app.animateSwitch.Value = false;
            app.DropDownPlotType = uidropdown(app.GridLayout);
            app.DropDownPlotType.ValueChangedFcn = createCallbackFcn(app, @DropDownPlotTypeValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GEWinspector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end